classdef EmotionSelectionUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HappyButton                 matlab.ui.control.Button
        SadButton                   matlab.ui.control.Button
        CalmButton                  matlab.ui.control.Button
        HypeButton                  matlab.ui.control.Button
        HowwouldyouliketofeelLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        data; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center");
            app.UIFigure.Visible = 'on';
            
            % Read in data from caller. 
            app.data = data;
            %disp(app.data)
        end

        % Button pushed function: HypeButton
        function HypeButtonPushed(app, event)
            Results(app.data, "Hype"); % Open results page -> Hype selected
            close(app.UIFigure);
        end

        % Button pushed function: CalmButton
        function CalmButtonPushed(app, event)
            Results(app.data, "Calm");  % Open results page -> Calm selected
            close(app.UIFigure);
        end

        % Button pushed function: HappyButton
        function HappyButtonPushed(app, event)
            Results(app.data, "Happy");  % Open results page -> Happy selected
            close(app.UIFigure);
        end

        % Button pushed function: SadButton
        function SadButtonPushed(app, event)
            Results(app.data, "Sad");  % Open results page -> Sad selected
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'DetailedBaseBackground.jpg');
            app.HowwouldyouliketofeelLabel = uilabel(app.UIFigure);
            app.HowwouldyouliketofeelLabel.Text = 'How would you like to feel?';
            app.HypeButton = uibutton(app.UIFigure, 'push');
            app.HypeButton.ButtonPushedFcn = createCallbackFcn(app, @HypeButtonPushed, true);
            app.HypeButton.Icon = fullfile(pathToMLAPP, 'HypeButton.jpg');
            app.HypeButton.Text = '';
            app.CalmButton = uibutton(app.UIFigure, 'push');
            app.CalmButton.ButtonPushedFcn = createCallbackFcn(app, @CalmButtonPushed, true);
            app.CalmButton.Icon = fullfile(pathToMLAPP, 'CalmButton.jpg');
            app.CalmButton.Text = '';
            app.SadButton = uibutton(app.UIFigure, 'push');
            app.SadButton.ButtonPushedFcn = createCallbackFcn(app, @SadButtonPushed, true);
            app.SadButton.Icon = fullfile(pathToMLAPP, 'SadButton.jpg');
            app.SadButton.Text = '';
            app.HappyButton = uibutton(app.UIFigure, 'push');
            app.HappyButton.ButtonPushedFcn = createCallbackFcn(app, @HappyButtonPushed, true);
            app.HappyButton.Icon = fullfile(pathToMLAPP, 'HappyButton.jpg');
            app.HappyButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EmotionSelectionUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end