classdef Results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Breakdown                      matlab.ui.control.Label
        HereswhatwebuiltforyouLabel    matlab.ui.control.Label
        PreviousRecommendationsButton  matlab.ui.control.Button
        SelectanotheremotionButton     matlab.ui.control.Button
        ExploreMoreRecommendationsButton  matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        Background                     matlab.ui.control.Image
    end

    
    properties (Access = public)  
        currSongNum = 0;
        highestCurrSongNum = 0;

        allData;        % Holds all song info.
        chosenEmotion;  % String value of the chosen emotion. % Emotions: 'Happy', 'Sad', 'Dynamic', 'Relaxed'

        songChoiceData; % Holds just the info of the emotion chosen.

        calmSongs;      % Holds the info of each respective emotion.
        hypeSongs;
        happySongs;
        sadSongs;

    end
    
    properties (Access = private)
    end
 
    methods (Access = public)
        %% FRONTENT - UI MANAGEMENT
        % Header function, called when app first opened. 
        % (Following retrieving all of the recommendation data).
        function CreateUI(app) 
            SetBackground(app);
            CreateResultsUI(app);
            CreateDemographicsData(app);
        end
           
        function CreateDemographicsData(app)
            numOfSongs = size(app.allData);
            numOfChosenSongs = size(app.songChoiceData)

            breakdownText = strcat("We found that ", num2str(numOfChosenSongs(1))," of your ", num2str(numOfSongs(1))," songs are ", app.chosenEmotion, "!");

            app.Breakdown.Text = breakdownText; 

        end
        % Takes in the emotion that the user choose as a string, this
        % information will be passed in from the emotion choice page. 
        %
        % Then, changes the background image accordingly, to the background
        % of that emotion, as designed by Caden. 
        function SetBackground(app) 
            calmImgSrc = 'CalmBackground.jpg';
            happyImgSrc = 'HappyBackground.jpg';
            sadImgSrc = 'SadBackground.jpg';
            dynamicImgSrc = 'HypeBackground.jpg';

            if(strcmp(app.chosenEmotion, "Happy"))
                app.Background.ImageSource = happyImgSrc;
            end
            
            if(strcmp(app.chosenEmotion, "Relaxed"))
                app.Background.ImageSource = calmImgSrc;
            end
            
            if(strcmp(app.chosenEmotion, "Sad"))
                app.Background.ImageSource = sadImgSrc;
            end
            
            if(strcmp(app.chosenEmotion, "Dynamic"))
                app.Background.ImageSource = dynamicImgSrc;
            end
        end
        

        % Takes in the recommendation data, that is recieved from the
        % imported information.
        %
        % Then, takes this data and presents it to the user, in the order
        % that it is ranked. Retrieving and showing the artist, song name,
        % cover, and a link to a sound clip.
        function CreateResultsUI(app)
           data = app.songChoiceData;
           numOfSongs = size(data); 
           numOfSongs = numOfSongs(1);      % How many total songs we can present.
            
           % For each slot that we have available: 
           for(i = 1:5)
                % Check if we need to show explore more, and previous
                % buttons. 
                CheckRecommendationButtonVisibility(app);
                
                % If we have more songs to show, retrieve info & create UI
                % section.
                if(app.currSongNum < numOfSongs)
                    songTitle = data.name(app.currSongNum + 1);
                    artist = data.artist(app.currSongNum + 1);
                    coverImgSrc = data.cover(app.currSongNum + 1);
                    audioLink = data.preview(app.currSongNum + 1);
                    audioLink = audioLink{1};

                    CreateSongPanel(app, songTitle, artist, coverImgSrc, audioLink, i); 
                    app.currSongNum = app.currSongNum + 1;
                    
                    if(app.currSongNum > app.highestCurrSongNum) 
                        app.highestCurrSongNum = app.currSongNum;
                    end
                end
           end
       end
        
        
       function CreateSongPanel(app, songTitle, artist, coverImgSrc, audioLink, num)            
            screenPosition = get(app.UIFigure,'Position');
            screenWidth = screenPosition(3);
            screenHeight = screenPosition(4);

            sections = 6;
            paddingHeight = 10;
            paddingWidth  = 20;    

            sectionHeight = (screenHeight-(paddingHeight*sections)) / sections ;
            sectionWidth  = (screenWidth-paddingWidth*2) / 4;
           
            startX = paddingWidth + sectionWidth*2;
            startY = sectionHeight*(sections-num)-paddingHeight*(num-1);

            coverPosition = [startX startY sectionHeight sectionHeight];
            titlePosition = [startX+sectionHeight+paddingWidth startY sectionWidth sectionHeight];
            audioPosition = [startX+sectionHeight*4+paddingWidth*2 startY sectionWidth sectionHeight];
            
            fileUrl = strcat('image',num2str(app.currSongNum+1),'.png');
            urlwrite(coverImgSrc{1}, fileUrl);
            
            cover = uiimage(app.UIFigure,'ImageSource',fileUrl,'ScaleMethod','fit','Position',coverPosition);
            cover.Tag = strcat('coverLbl', num2str(num));

            songLbl = uilabel(app.UIFigure, 'Text', strcat(num2str(app.currSongNum+1),'. ', songTitle,", by ",artist), 'WordWrap', 'on', 'FontSize', 24, 'Position', titlePosition, 'FontColor','White');              
            songLbl.Tag = strcat('songLbl', num2str(num)); 
            
            % IF there is an audio link -> present it to the user.
            if(~isempty(audioLink))
                %disp('link present');
                %urlLink = "spotify.com/us";
                % strcat('audioLbl', num2str(app.currSongNum)
                audioLink = uihyperlink(app.UIFigure, 'Text', 'Listen Here', 'URL', audioLink, 'Position', audioPosition, 'FontSize', 24, 'FontColor','White');
                audioLink.Tag = strcat('audioLbl', num2str(num));
            else 
                %disp('no link!!')
            end
       end
    
        function CheckRecommendationButtonVisibility(app)
            numOfSongs = size(app.songChoiceData);
            numOfSongs = numOfSongs(1);

            if(app.currSongNum >= numOfSongs)
                disp('hide button!!');
                % hide get more
                app.ExploreMoreRecommendationsButton.Visible = 'off';
            else 
                % show get more
                app.ExploreMoreRecommendationsButton.Visible = 'on';
            end

            if(app.currSongNum > 5)
                app.PreviousRecommendationsButton.Visible = 'on';
            else
                app.PreviousRecommendationsButton.Visible = 'off';
            end
       end
        
        function ClearSongResults(app)
             % Clear information (rn just 
            for(i = 1:5) 
                searchLbl = strcat('songLbl', num2str(i));
                songTitle = findall(groot, 'Tag', searchLbl);
                delete(songTitle);

                searchCover = strcat('coverLbl', num2str(i));
                coverFound = findall(groot, 'Tag', searchCover);
                delete(coverFound);

                searchAudio = strcat('audioLbl', num2str(i));
                audioLink = findall(groot, 'Tag', searchAudio);
                delete(audioLink);
            end

            %for(i = app.currSongNum-5:a)
        end

        function DeleteDownloadedImages(app)
            for(i = 1:app.highestCurrSongNum)
                imgLocation = strcat('image', num2str(i),'.png');
                delete(imgLocation)
            end
        end
        
        %% BACKEND - GETTING RECOMMENDATIONS FROM DATA
        % Takes in data from the upload page:
        % Uses DataHandler static methods, to get each type of emotion song
        % and saves them in the respective global variables.
        function GetRecommendations(app)
            %disp('get recs!')

            app.calmSongs = DataHandler.getRelaxedSongs(app.allData);     % Relaxed
            app.happySongs = DataHandler.getHappySongs(app.allData)       % Happy
            app.sadSongs = DataHandler.getSadSongs(app.allData);          % Sad
            app.hypeSongs = DataHandler.getDynamicSongs(app.allData);     % Dynamic

            if(strcmp(app.chosenEmotion, "Happy"))
                app.songChoiceData = app.happySongs;
            end
            
            if(strcmp(app.chosenEmotion, "Relaxed"))
                app.songChoiceData = app.calmSongs;
            end
            
            if(strcmp(app.chosenEmotion, "Sad"))
                app.songChoiceData = app.sadSongs;
            end
            
            if(strcmp(app.chosenEmotion, "Dynamic"))
                app.songChoiceData = app.hypeSongs;
            end
            
            % TESTING
%             disp('happy song recs')
%             disp(app.happySongs)
% 
%             disp('sad song recs')
%             disp(app.sadSongs)
% 
%             disp('calm song recs')
%             disp(app.calmSongs)
% 
%             disp('hype song recs')
%             disp(app.hypeSongs)
% 
%             disp('chosen emotion song recs')
%             disp(app.songChoiceData)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, emotion)
            % WHEN THE PAGE FIRST OPENS:

            app.UIFigure.Visible = 'off';       % Hide while sorting through data.
            movegui(app.UIFigure, 'center');
            
            % Read in data from input. 
            app.allData = data;
            app.chosenEmotion = emotion;
          
            GetRecommendations(app);            % Get song info.
            CreateUI(app);                      % Create results UI.    

            app.UIFigure.Visible = 'on';        % Show UI to user.
        end

        % Button pushed function: ExploreMoreRecommendationsButton
        function ExploreMoreRecommendationsButtonPushed(app, event)
            ClearSongResults(app);% Remove any pre-existing data on screen.

            CreateResultsUI(app); % Add the next results (if there are any)
        end

        % Button pushed function: PreviousRecommendationsButton
        function PreviousRecommendationsButtonPushed(app, event)
            ClearSongResults(app);  % Clear any pre-existing data on screen.
            
            goBackAmount = mod(app.currSongNum, 5);
            
            if(goBackAmount == 0) 
                goBackAmount = 5;
            end

            app.currSongNum = app.currSongNum - 5 - goBackAmount;     
            if(app.currSongNum < 0) % Keep current song at 0 or above. 
                app.currSongNum = 0;
            end

            CreateResultsUI(app);   % Add previous songs, based on currSongNum.
        end

        % Button pushed function: SelectanotheremotionButton
        function SelectanotheremotionButtonPushed(app, event)
            % If 'Select another emotion' button is pressed, go back to UI
            % passing current data.
            EmotionSelectionUI(app.allData);        
            close(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            DeleteDownloadedImages(app)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = 'BaseBackground.jpg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ExploreMoreRecommendationsButton = uibutton(app.UIFigure, 'push');
            app.ExploreMoreRecommendationsButton.ButtonPushedFcn = createCallbackFcn(app, @ExploreMoreRecommendationsButtonPushed, true);
            app.ExploreMoreRecommendationsButton.Text = 'Explore More Recommendations';
            app.SelectanotheremotionButton = uibutton(app.UIFigure, 'push');
            app.SelectanotheremotionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectanotheremotionButtonPushed, true);
            app.SelectanotheremotionButton.Text = 'Select another emotion';
            app.PreviousRecommendationsButton = uibutton(app.UIFigure, 'push');
            app.PreviousRecommendationsButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousRecommendationsButtonPushed, true);
            app.PreviousRecommendationsButton.Text = 'Previous Recommendations';
            app.HereswhatwebuiltforyouLabel = uilabel(app.UIFigure);
            app.HereswhatwebuiltforyouLabel.Text = 'Here''s what we built for you:';
            app.Breakdown = uilabel(app.UIFigure);
            app.Breakdown.Text = 'We found that 60 of your 120 songs are sad!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end