classdef UploadUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ImsorrythatwasnotthecorrectfilepleasetryagainLabel  matlab.ui.control.Label
        NeedHelpButton  matlab.ui.control.Button
        UploadButton    matlab.ui.control.Button
        Image           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ImsorrythatwasnotthecorrectfilepleasetryagainLabel.Visible = 'off';
        end

        % Button pushed function: NeedHelpButton
        function NeedHelpButtonPushed(app, event)
            HelpUI; % Take user to the Tutorial Page 1 (if need help)
            close(app.UIFigure);
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');  % Open getting file UI
            filename = fullfile(path, file);    
            data = readtable(filename);        % Read in csv to table
            
            exists = any(strcmp('preview', data.Properties.VariableNames));
            
            % CHECK IF DATA IS VALID
            if(exists == 0)
                % IF NOT - SAY TO USER: 
                app.ImsorrythatwasnotthecorrectfilepleasetryagainLabel.Visible = 'on';
            else % IF IT IS: CONTINUE TO EMOTION PAGE (SEND DATA WITH)
                close(app.UIFigure);
                
                % Process Data to correct format before sending to
                % recommendations.
                data.valence = DataHandler.normToRange(data.valence, 0, 1, -1, 1);
                data.energy = DataHandler.normToRange(data.energy, 0, 1, -1, 1);
                
                % Open emotion selection page.
                EmotionSelectionUI(data);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UploadBackground.jpg');
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.NeedHelpButton = uibutton(app.UIFigure, 'push');
            app.NeedHelpButton.ButtonPushedFcn = createCallbackFcn(app, @NeedHelpButtonPushed, true);
            app.NeedHelpButton.Text = 'Need Help?';
            app.ImsorrythatwasnotthecorrectfilepleasetryagainLabel = uilabel(app.UIFigure);
            app.ImsorrythatwasnotthecorrectfilepleasetryagainLabel.Text = {'I''m sorry that was not the correct file, please try again.'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UploadUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end