classdef ImportMusicInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        UploadButton  matlab.ui.control.Button
        ImportthedownloadfileherepleaseLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        songData % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [file,path] = uigetfile('*.csv')  %open a mat file
            filename = fullfile(path, file)
            data = readtable(filename);
            
            exists = any(strcmp('valence', data.Properties.VariableNames))

            if(exists == 0)
                disp('incorrect file type')
            end
            
            % Data Processing 
            data.valence = DataHandler.normToRange(data.valence, 0, 1, -1, 1);
            data.energy = DataHandler.normToRange(data.energy, 0, 1, -1, 1);
            
            % ------ Recommendations
            
            % DataHandler.plotEnergyValence(data);
            
            relaxedSongs = DataHandler.getRelaxedSongs(data);     % Relaxed
            happySongs = DataHandler.getHappySongs(data)       % Happy
            sadSongs = DataHandler.getSadSongs(data);             % Sad
            dynamicSongs = DataHandler.getDynamicSongs(data);     % Dynamic
            
            % handle if they don't input the correct file ***8

            Results(happySongs, "Happy") % Open results page with the given data
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportthedownloadfileherepleaseLabel = uilabel(app.UIFigure);
            app.ImportthedownloadfileherepleaseLabel.Text = 'Import the download file here please';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportMusicInfo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end