classdef Results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PreviousRecommendationsButton  matlab.ui.control.Button
        SelectanotheremotionButton     matlab.ui.control.Button
        ExploreMoreRecommendationsButton  matlab.ui.control.Button
        HereswhatwefoundforyouLabel    matlab.ui.control.Label
        Background                     matlab.ui.control.Image
    end

    
    properties (Access = public)  
        currSongNum = 0;
        data;
    end
    
    properties (Access = private)
    end
 
    methods (Access = public)

        function createUI(app, data, emotion) 
            % Emotions: 'Happy', 'Sad', 'Dynamic', 'Relaxed'
            
            % STEP 1 - Setup background image!
            SetBackground(app, emotion);
            
            % STEP 2 - Setup results info!
            CreateResultsUI(app, data);
        end
           
        % STEP 1: Executed first,
        % Takes in the emotion that the user choose as a string, this
        % information will be passed in from the emotion choice page. 
        %
        % Then, changes the background image accordingly, to the background
        % of that emotion, as designed by Caden. 
        function SetBackground(app, emotion) 
            calmImgSrc = 'CalmButtonNoText.jpg';
            happyImgSrc = 'HappyButtonNoText.jpg';
            sadImgSrc = 'SadButtonNoText.jpg';
            dynamicImgSrc = 'HypeButtonNoText.jpg';

            if(strcmp(emotion, "Happy"))
                app.Background.ImageSource = happyImgSrc;
            end
            
            if(strcmp(emotion, "Calm"))
                app.Background.ImageSource = calmImgSrc;
            end
            
            if(strcmp(emotion, "Sad"))
                app.Background.ImageSource = sadImgSrc;
            end
            
            if(strcmp(emotion, "Dynamic"))
                app.Background.ImageSource = dynamicImgSrc;
            end
            
        end
        
        % STEP 2: Executed second,
        % Takes in the recommendation data, that is recieved from the
        % imported information.
        %
        % Then, takes this data and presents it to the user, in the order
        % that it is ranked. Retrieving and showing the artist, song name,
        % cover, and a link to a sound clip.
        function CreateResultsUI(app, data)
           numOfSongs = size(data); 
           numOfSongs = numOfSongs(1);      % How many total songs we can present.
            
           % For each slot that we have available: 
           for(i = 1:5)
                % Check if we need to show explore more, and previous
                % buttons. 
                checkRecommendationButtonVisibility(app, data);
                
                % If we have more songs to show, retrieve info & create UI
                % section.
                if(app.currSongNum < numOfSongs)
                    songTitle = data.name(app.currSongNum + 1);
                    artist = data.artist(app.currSongNum + 1);
                    coverImgSrc = data.cover(app.currSongNum + 1);
                    
                    CreateSongPanel(app, songTitle, artist, coverImgSrc, i); 
                    app.currSongNum = app.currSongNum + 1;
                end
           end
       end

       function CreateSongPanel(app, songTitle, artist, coverImgSrc, num)            
            screenPosition = get(app.UIFigure,'Position');
            screenWidth = screenPosition(3);
            screenHeight = screenPosition(4);

            sections = 6;
            paddingHeight = 10;
            paddingWidth  = 20;    

            sectionHeight = (screenHeight-(paddingHeight*sections)) / sections ;
            sectionWidth  = (screenWidth-paddingWidth*2) / 4;
           
            startX = paddingWidth + sectionWidth*2;
            startY = sectionHeight*(sections-num)-paddingHeight*(num-1);

            coverPosition = [startX startY sectionHeight sectionHeight];
            titlePosition = [startX+sectionHeight+paddingWidth startY sectionWidth sectionHeight];
                            
            fileUrl = strcat('image',num2str(app.currSongNum+1),'.png');
            urlwrite(coverImgSrc{1}, fileUrl);
            
            cover = uiimage(app.UIFigure,'ImageSource',fileUrl,'ScaleMethod','fit','Position',coverPosition);
            cover.Tag = strcat('coverLbl', num2str(num));

            songLbl = uilabel(app.UIFigure, 'Text', strcat(num2str(app.currSongNum+1),'. ', songTitle,", by ",artist), 'WordWrap', 'on', 'FontSize', 24, 'Position', titlePosition, 'FontColor','White');              
            songLbl.Tag = strcat('songLbl', num2str(num)); 
            
            
            % AUDIO CLIP
            % uihyperlink
            % songClip = uihtml(section);
            % urlwrite(audioSrc, 'testAudio.wav')
            
            % songClip.HTMLSource = '<audio controls style="padding-left:20px"><source src="/testAudio.wav" type="audio/wav"></audio>'
            % audioSrc = 'https://p.scdn.co/mp3-preview/1e7df67cef52cd4578f2a344f4bc875cac394301?cid=28ca2d3814414c08858c196d669a3838'
       end
    
       function checkRecommendationButtonVisibility(app, data)
           
           numOfSongs = size(data);
           numOfSongs = numOfSongs(1);

            if(app.currSongNum >= numOfSongs)
                disp('hide button!!');
                % hide get more
                app.ExploreMoreRecommendationsButton.Visible = 'off'
            else 
                % show get more
                app.ExploreMoreRecommendationsButton.Visible = 'on'
            end

            if(app.currSongNum > 5)
                app.PreviousRecommendationsButton.Visible = 'on'
            else
                app.PreviousRecommendationsButton.Visible = 'off'
            end
       end
        
       function clearSongResults(app)
             % Clear information (rn just 
            for(i = 1:5) 
                searchLbl = strcat('songLbl', num2str(i));
                songTitle = findall(groot, 'Tag', searchLbl);
                delete(songTitle)

                SearchCover = strcat('coverLbl', num2str(i))
                songCover = findall(groot, 'Tag', SearchCover)
                delete(songCover)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, emotion)
            createUI(app, data, emotion);
            app.data = data;
            movegui(app.UIFigure, 'center');
           
        end

        % Button pushed function: ExploreMoreRecommendationsButton
        function ExploreMoreRecommendationsButtonPushed(app, event)
            disp('more recs!!')
            
            clearSongResults(app)
            
            CreateResultsUI(app, app.data)
        end

        % Button pushed function: PreviousRecommendationsButton
        function PreviousRecommendationsButtonPushed(app, event)
            disp('go back!!')

            clearSongResults(app)

            app.currSongNum = app.currSongNum - 10;
            if(app.currSongNum < 0)
                app.currSongNum = 0;
            end
            CreateResultsUI(app, app.data)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = 'BackG Gradient Design FINAL.jpg';
            app.HereswhatwefoundforyouLabel = uilabel(app.UIFigure);
            app.HereswhatwefoundforyouLabel.Text = 'Here''s what we found for you: ';
            app.ExploreMoreRecommendationsButton = uibutton(app.UIFigure, 'push');
            app.ExploreMoreRecommendationsButton.ButtonPushedFcn = createCallbackFcn(app, @ExploreMoreRecommendationsButtonPushed, true);
            app.ExploreMoreRecommendationsButton.Text = 'Explore More Recommendations';
            app.SelectanotheremotionButton = uibutton(app.UIFigure, 'push');
            app.SelectanotheremotionButton.Text = 'Select another emotion';
            app.PreviousRecommendationsButton = uibutton(app.UIFigure, 'push');
            app.PreviousRecommendationsButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousRecommendationsButtonPushed, true);
            app.PreviousRecommendationsButton.Text = 'Previous Recommendations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end