classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        NeedHelpButton        matlab.ui.control.Button
        LoginToSpotifyButton  matlab.ui.control.Button
        PATHOSLabel           matlab.ui.control.Label
        Image                 matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: LoginToSpotifyButton
        function LoginToSpotifyButtonPushed(app, event)
            web('https://accounts.spotify.com/en/login/?continue=https%3A//open.spotify.com/__noul__%3Fl2l%3D1%26nd%3D1&_locale=en-US');
        end

        % Button pushed function: NeedHelpButton
        function NeedHelpButtonPushed(app, event)
            HelpUI
            close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '3055d3dc-3064-49c1-2d81-76a228804707._RI_.jpg');
            app.PATHOSLabel = uilabel(app.UIFigure);
            app.PATHOSLabel.Text = 'PATHOS';
            app.LoginToSpotifyButton = uibutton(app.UIFigure, 'push');
            app.LoginToSpotifyButton.ButtonPushedFcn = createCallbackFcn(app, @LoginToSpotifyButtonPushed, true);
            app.LoginToSpotifyButton.Text = 'Login To Spotify';
            app.NeedHelpButton = uibutton(app.UIFigure, 'push');
            app.NeedHelpButton.ButtonPushedFcn = createCallbackFcn(app, @NeedHelpButtonPushed, true);
            app.NeedHelpButton.Text = 'Need Help?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end